classdef ElecLandmarkAlignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LFPRightClip              matlab.ui.control.Button
        LFPLeftClip               matlab.ui.control.Button
        SaveResultButton          matlab.ui.control.Button
        AlignmentpreviewerButton  matlab.ui.control.Button
        SelectPathButton          matlab.ui.control.Button
        SessPath                  matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        LFPpower                  matlab.ui.control.UIAxes
        ChannelArea_Ax            matlab.ui.control.UIAxes
        LFP_Ax                    matlab.ui.control.UIAxes
        Amplitude_Ax              matlab.ui.control.UIAxes
        SpikeRate_Ax              matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        ksRawfilePath = ''; % ks raw data path
        ksOutfilePath = ''; % ks output data path
        ksSortingType = 'ks2_5';
        
        lfpDefaultTimeLen = 30; % 20 seconds data from the start
        lfpDefaultClip = 2; % seconds block used for lfp amplitude plot, that is the 2nd seconds after start
        lfpUsedClip = 2;
        
        % NP 1.0 default parameters
        lfpfs = 2500; % default lfp sample rate
        apfs = 30000; % default ap sample rate
        NumChn = 385;
        NumSamples = [];
        
        
        % loaded datas
        lfpData = [];
        sp
        spTimes
        spAmps
        spDepths
        spSites
        ChnAreaIndex = [];
        ChnAreaNames = {};
        AllenatlasPath = 'E:\MatCode\AllentemplateData';
        chanMap
        Colormap
        allPowerEst
        F
        
        % plot settings 
        commonYScales = [nan, nan];
        YValues = 10:10:3840; % to distinguish same depthed channels
        yTicks = 0:500:3840;
        AllAxYlims = {[0 3850],[0 3850],[0 3850],[0 3850],[0 3850]};
        AllAxPos = {[];[];[];[];[]};
        LastestYlims = [];
        
    end
    
    methods (Access = private)
        
        function LoadDataANDplot(app)
            % function used to load data from selected path and plot all
            % the criterias
            if isempty(app.ksOutfilePath) || isempty(app.ksRawfilePath)
                warning('Session data path have not been defined.');
                return;
            end
            
            % load lfp data
            LFPrawfilepathDir = dir(fullfile(app.ksRawfilePath,'*.lf.bin'));
            LFPrawfilepath = fullfile(app.ksRawfilePath,LFPrawfilepathDir(1).name);
            dataTypeNBytes = numel(typecast(cast(0, 'int16'), 'uint8'));
            
            app.NumSamples = LFPrawfilepathDir(1).bytes/(dataTypeNBytes*app.NumChn);
            lfpDataFull = memmapfile(LFPrawfilepath, 'Format', {'int16', [app.NumChn app.NumSamples], 'x'});
            app.lfpData = lfpDataFull.Data.x(:,1:(app.lfpDefaultTimeLen*app.lfpfs));
            
            plotClipData = app.lfpData(:,((app.lfpDefaultClip-1)*app.lfpfs+1):app.lfpDefaultClip*app.lfpfs);
            xValues = (0:size(plotClipData,2))/app.lfpfs;
            
            plotClipData = plotClipData - median(plotClipData);
            plotClipData = (plotClipData' - median(plotClipData'))'; %#ok<UDIM> 
            
            imagesc(app.LFP_Ax,xValues,app.YValues,plotClipData);
            set(app.LFP_Ax,'ylim',[0 3850]);
            xlabel(app.LFP_Ax,'Time (s)');
            ylabel(app.LFP_Ax,'Depth (um)');
%             app.AllAxYlims{3} = get(app.LFP_Ax,'ylim');
            app.LastestYlims = get(app.LFP_Ax,'ylim');
            app.AllAxPos{1} = get(app.LFP_Ax,'position');
            fprintf('Plot LFP amplitude.\n');
            
            % load spike rate datas
            app.sp = loadKSdir(app.ksOutfilePath);
            [app.spTimes, app.spAmps, app.spDepths, app.spSites] = ...
                ksDriftmap(app.ksOutfilePath);
            
            depthBins = 0:20:3840;
            recordingDur = app.sp.st(end);
            [SpAmps, SpRates] = WFampsOverDepth(app.spTimes, app.spDepths, depthBins, recordingDur);
            DepthCenter = (depthBins(1:end-1)+depthBins(2:end))/2;
            
            
            % plot spike rate datas
            plot(app.SpikeRate_Ax,SpRates,DepthCenter);
            set(app.SpikeRate_Ax,'ylim',[0 3850]);
            xlabel(app.SpikeRate_Ax,'FireRate (Hz)');
            ylabel(app.SpikeRate_Ax,'Depth (um)');
            app.AllAxPos{2} = get(app.SpikeRate_Ax,'position');
            fprintf('Plot Spike rates.\n');
            
            % plot spike amplitude, 95% percentile values
            plot(app.Amplitude_Ax,SpAmps,DepthCenter);
            set(app.Amplitude_Ax,'ylim',[0 3850]);
            xlabel(app.Amplitude_Ax,'Amplitude (Unit)');
            ylabel(app.Amplitude_Ax,'Depth (um)');
            set(app.Amplitude_Ax,'YDir','Reverse');
            app.AllAxPos{3} = get(app.Amplitude_Ax,'position');
            fprintf('Plot Spike amplitude.\n');
            
            %plot the LFP power, for different frequency bands
            if isempty(app.allPowerEst) || isempty(app.chanMap)
                [~, app.allPowerEst, app.F, ~] = lfpBandPower(...
                    LFPrawfilepath,app.lfpfs,app.NumChn,[]);
                app.chanMap = readNPY(fullfile(app.ksOutfilePath, 'channel_map.npy'));
    %             nC = length(chanMap);
                app.allPowerEst = app.allPowerEst(:,app.chanMap+1)'; % now nChans x nFreq
            end
            
            freqBands = {[1.5 4], [4 10], [10 30], [30 80], [80 200]};
            NumFreqBands = length(freqBands);
            hold(app.LFPpower,'on');
            lineColors = cool(NumFreqBands);
            for cfb = 1 : NumFreqBands
                inclF = app.F > freqBands{cfb}(1) & app.F <= freqBands{cfb}(2);
                cPower = mean(10*log10(app.allPowerEst(:,inclF)),2);
                plot(app.LFPpower,cPower, app.chanMap*10,'Color',lineColors(cfb,:));
                
            end
%             set(app.LFPpower,'ytick',[]);
            ylim(app.LFPpower,[0 3850]);
            xlabel(app.LFPpower,'Power (dB)');
            ylabel(app.LFPpower,'Depth (um)');
%             legend(app.LFPpower,cellfun(@(x) sprintf('%.1f - %.1f Hz',x(1),x(2)),freqBands,'UniformOutput',0));
            app.AllAxPos{4} = get(app.LFPpower,'position');
            fprintf('Plot LFP band power.\n');
            
            % load channel areas and trajectory areas
            ChnStrFilePath = fullfile(app.ksOutfilePath,'Chnlocation.mat');
            ChnposData = load(ChnStrFilePath,'ChnArea_Strings');
            app.ChnAreaIndex = cell2mat(ChnposData.ChnArea_Strings(2:end,2));
            app.ChnAreaNames = ChnposData.ChnArea_Strings(2:end,3);


            ChnPosSteps = [0:20:3820;0:20:3820];
            ChnPosSteps = ChnPosSteps(:);
            probeAreaPlotFun(app,app.ChnAreaIndex,ChnPosSteps);
            
            app.AllAxPos{5} = get(app.ChannelArea_Ax,'position');
            
            % for axis position alignments
            set(app.ChannelArea_Ax,'xtick',1);
            xlabel(app.ChannelArea_Ax,'Areas')
            
        end
        
        function probeAreaPlotFun(app,ChnAreas,ChnPos)
            st = loadStructureTree([app.AllenatlasPath filesep 'structure_tree_safe_2017.csv']);
            
            trajectory_area_boundary_idx = [1;find(diff(double(ChnAreas)) ~= 0)+1;384];
            trajectory_area_boundaries = ChnPosSteps(trajectory_area_boundary_idx);
            trajectory_area_centers = (trajectory_area_boundaries(1:end-1) + diff(trajectory_area_boundaries)/2);
            trajectory_area_labels = st.acronym(ChnAreas(trajectory_area_boundary_idx));
            
            image(app.ChannelArea_Ax,[],ChnPos,ChnAreas);
            colormap(app.ChannelArea_Ax,app.Colormap.cmap);
            set(app.ChannelArea_Ax,'yTick',trajectory_area_centers,'yticklabel',trajectory_area_labels,...
                'xtick',[],'YAxisLocation','right');
            ylabel(app.ChannelArea_Ax,[]);
            xlabel(app.ChannelArea_Ax,[]);
            ylim(app.ChannelArea_Ax,[0 3850]);
            for cBound = 1 : length(trajectory_area_boundaries)
                line(app.ChannelArea_Ax,[-10.5,1.5],trajectory_area_boundaries(cBound)*[1 1],'Color','r',...
                    'linewidth',1.5,'linestyle','--');
            end
            set(app.ChannelArea_Ax,'Clipping','off');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % check whether the Allen CCF path is valid
            if ~exist(app.AllenatlasPath,'dir')
                fprintf('Default allen ccf file folder non-exist. Please select new position.\n');
                CCFFolder = uigetdir(pwd,'Select AllenCCF folder');
                if ~ischar(CCFFolder) || ~exist(fullfile(CCFFolder,'template_volume_10um.npy'),'file')
                    warning('The selected folder do not have desired files, existing...');
                    return;
                else
                    app.AllenatlasPath = CCFFolder;
                end
                
            end
            allenCCF_path = fileparts(which('allenCCFbregma'));
            cmap_filename = [allenCCF_path filesep 'allen_ccf_colormap_2017.mat'];
            app.Colormap = load(cmap_filename);
            
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            folds = uigetdir(pwd);
            if ~ischar(folds)
                % no path is selected
                return;
            end
            app.ksRawfilePath = folds;
            app.ksOutfilePath = fullfile(folds, app.ksSortingType);
            
            LFPrawfilepathStrc = dir(fullfile(folds,'*.lf.bin'));
            if isempty(LFPrawfilepathStrc) || ~exist(fullfile(app.ksOutfilePath,'spike_times.npy'),'file')
                warning('The selected path do not have needed files for further analysis.');
                return;
            end
            set(app.SessPath,'Value',folds);
            
            LoadDataANDplot(app);
        end

        % Value changed function: SessPath
        function SessPathValueChanged(app, event)
            folds = app.SessPath.Value;
            if ~ischar(folds)
                % no path is selected
                return;
            end
            app.ksRawfilePath = folds;
            app.ksOutfilePath = fullfile(folds, app.ksSortingType);
            
            LFPrawfilepathStrc = dir(fullfile(folds,'*.lf.bin'));
            if isempty(LFPrawfilepathStrc) || ~exist(fullfile(app.ksOutfilePath,'spike_times.npy'),'file')
                warning('The selected path do not have needed files for further analysis.');
                return;
            end
            LoadDataANDplot(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            if any(strcmpi(event.Modifier,'shift'))
                yChange = 5;
            else
                yChange = 50;
            end
            switch key
                % move the probe area upward
                case 'uparrow'
                    Newylim = get(app.ChannelArea_Ax,'ylim') - yChange;
                    ylim(app.ChannelArea_Ax,Newylim);
                    app.AllAxYlims{5} = {Newylim};
                    
                case 'downarrow'
                    Newylim = get(app.ChannelArea_Ax,'ylim') + yChange;
                    ylim(app.ChannelArea_Ax,Newylim);
                    app.AllAxYlims{5} = {Newylim};
            end
                    
            
        end

        % Button pushed function: LFPRightClip
        function LFPRightClipButtonPushed(app, event)
            lfpDatapoints = size(app.lfpData,2);
            app.lfpUsedClip = app.lfpUsedClip + 1;
            if app.lfpUsedClip*app.lfpfs > lfpDatapoints
                app.lfpUsedClip = app.lfpUsedClip - 1;
                warning('Clip index out-of-range.');
                return;
            end
            plotClipData = app.lfpData(:,((app.lfpUsedClip-1)*app.lfpfs+1):app.lfpUsedClip*app.lfpfs);
            xValues = (0:size(plotClipData,2))/app.lfpfs;
            
            plotClipData = plotClipData - median(plotClipData);
            plotClipData = (plotClipData' - median(plotClipData'))'; %#ok<UDIM> 
            
            imagesc(app.LFP_Ax,xValues,app.YValues,plotClipData);
            set(app.LFP_Ax,'ylim',[0 3850]);
            xlabel(app.LFP_Ax,'Time (s)');
            ylabel(app.LFP_Ax,'Depth (um)');
%             app.AllAxYlims{3} = get(app.LFP_Ax,'ylim');
            app.LastestYlims = get(app.LFP_Ax,'ylim');
            fprintf('Plot LFP amplitude.\n');
        end

        % Button pushed function: LFPLeftClip
        function LFPLeftClipButtonPushed(app, event)
            
            app.lfpUsedClip = app.lfpUsedClip - 1;
            if app.lfpUsedClip < 1
                app.lfpUsedClip = app.lfpUsedClip + 1;
                warning('Clip index out-of-range.');
                return;
            end
            plotClipData = app.lfpData(:,((app.lfpUsedClip-1)*app.lfpfs+1):app.lfpUsedClip*app.lfpfs);
            xValues = (0:size(plotClipData,2))/app.lfpfs;
            
            plotClipData = plotClipData - median(plotClipData);
            plotClipData = (plotClipData' - median(plotClipData'))'; %#ok<UDIM> 
            
            imagesc(app.LFP_Ax,xValues,app.YValues,plotClipData);
            set(app.LFP_Ax,'ylim',[0 3850]);
            xlabel(app.LFP_Ax,'Time (s)');
            ylabel(app.LFP_Ax,'Depth (um)');
%             app.AllAxYlims{3} = get(app.LFP_Ax,'ylim');
            app.LastestYlims = get(app.LFP_Ax,'ylim');
            fprintf('Plot LFP amplitude.\n');
        end

        % Button pushed function: AlignmentpreviewerButton
        function AlignmentpreviewerButtonPushed(app, event)
            % load data from probeCCF data and recalculate the aligned
            % probe areas
            probeCCFFilePath = fullfile(app.ksOutfilePath,'ProbeCCFdata.mat');
            if ~exist(probeCCFFilePath,'file')
                warning('Probe CCF data doesn''t exist.');
                return;
            end
            ProbeTrajData = load(probeCCFFilePath);
            cProbe_points = [ProbeTrajData.ProbeCCF.points,ProbeTrajData.ProbeCCF.endpoints];
            
            r0 = mean(cProbe_points,1);
            xyz = bsxfun(@minus,cProbe_points,r0);
            [U,S,V] = svd(xyz,0);
            histology_probe_direction = V(:,1);
            % (make sure the direction goes down in DV - flip if it's going up)
            if histology_probe_direction(2) < 0
                histology_probe_direction = -histology_probe_direction;
            end
            ProbDirectionAll = histology_probe_direction; 
            
            Online_probeDatapoints = U(:,1) * S(1,1) * (V(:,1))' + r0;
            probe_fit_endpoint = Online_probeDatapoints(end,:);
            
            ChnPosSteps = [20:20:3840;20:20:3840];
            ChnPosSteps = ChnPosSteps(:)/10; % convert to 10um pixel length
            Chn2EndpointDis = ChnPosSteps - app.AllAxYlims{5}(1)/10;
            
%             tv = readNPY([app.AllenatlasPath filesep 'template_volume_10um.npy']);
            av = readNPY([app.AllenatlasPath filesep 'annotation_volume_10um_by_index.npy']);
%             st = loadStructureTree([app.AllenatlasPath filesep 'structure_tree_safe_2017.csv']);
            
            CorrectedChnCords = (round((-1)*Chn2EndpointDis * (ProbDirectionAll)'...
                + probe_fit_endpoint));
            % in case any channel position is out of index
            chan_coords_outofbounds = ...
                            any(CorrectedChnCords' < 1,1) | ...
                            any(CorrectedChnCords' > size(av)',1);
            %
            if sum(chan_coords_outofbounds) % if channal out of index position exists
                ChnOutboundInds = chan_coords_outofbounds;
                chn_coords_idx = sub2ind(size(av), ...
                    CorrectedChnCords(~ChnOutboundInds,1),CorrectedChnCords(~ChnOutboundInds,2),...
                    CorrectedChnCords(~ChnOutboundInds,3));
            else
                chn_coords_idx = sub2ind(size(av), ...
                    CorrectedChnCords(:,1),CorrectedChnCords(:,2),CorrectedChnCords(:,3));
                ChnOutboundInds = [];
            end
            chn_areas = int16(av(chn_coords_idx));
            
            probeAreaPlotFun(app,chn_areas,ChnPosSteps);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.SpikeRate_Ax = uiaxes(app.UIFigure);
            title(app.SpikeRate_Ax, 'Spike Rate')
            xlabel(app.SpikeRate_Ax, 'X')
            ylabel(app.SpikeRate_Ax, 'Y')
            zlabel(app.SpikeRate_Ax, 'Z')
            app.SpikeRate_Ax.Tag = 'SpikeRate_Ax';
            app.Amplitude_Ax = uiaxes(app.UIFigure);
            title(app.Amplitude_Ax, 'Amplitude')
            xlabel(app.Amplitude_Ax, 'X')
            ylabel(app.Amplitude_Ax, 'Y')
            zlabel(app.Amplitude_Ax, 'Z')
            app.LFP_Ax = uiaxes(app.UIFigure);
            title(app.LFP_Ax, 'LFP Amplitude')
            xlabel(app.LFP_Ax, 'X')
            ylabel(app.LFP_Ax, 'Y')
            zlabel(app.LFP_Ax, 'Z')
            app.ChannelArea_Ax = uiaxes(app.UIFigure);
            title(app.ChannelArea_Ax, 'Channel Areas')
            xlabel(app.ChannelArea_Ax, 'X')
            ylabel(app.ChannelArea_Ax, 'Y')
            zlabel(app.ChannelArea_Ax, 'Z')
            app.LFPpower = uiaxes(app.UIFigure);
            title(app.LFPpower, 'LFP power')
            xlabel(app.LFPpower, 'X')
            ylabel(app.LFPpower, 'Y')
            zlabel(app.LFPpower, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.SessPath = uieditfield(app.UIFigure, 'text');
            app.SessPath.ValueChangedFcn = createCallbackFcn(app, @SessPathValueChanged, true);
            app.SelectPathButton = uibutton(app.UIFigure, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = 'Select Path';
            app.AlignmentpreviewerButton = uibutton(app.UIFigure, 'push');
            app.AlignmentpreviewerButton.ButtonPushedFcn = createCallbackFcn(app, @AlignmentpreviewerButtonPushed, true);
            app.AlignmentpreviewerButton.Text = 'Alignment previewer';
            app.SaveResultButton = uibutton(app.UIFigure, 'push');
            app.SaveResultButton.Text = 'Save Result';
            app.LFPLeftClip = uibutton(app.UIFigure, 'push');
            app.LFPLeftClip.ButtonPushedFcn = createCallbackFcn(app, @LFPLeftClipButtonPushed, true);
            app.LFPLeftClip.Text = '<';
            app.LFPRightClip = uibutton(app.UIFigure, 'push');
            app.LFPRightClip.ButtonPushedFcn = createCallbackFcn(app, @LFPRightClipButtonPushed, true);
            app.LFPRightClip.Text = '>';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElecLandmarkAlignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end